classdef logpose_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        HOMETab                 matlab.ui.container.Tab
        run_IQ_button           matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        UPLOADIQFILEButton      matlab.ui.control.Button
        ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel  matlab.ui.control.Label
        StartbyuploadingaIQfileontherightLabel  matlab.ui.control.Label
        WELCOMETOLOGPOSELabel   matlab.ui.control.Label
        IQTab                   matlab.ui.container.Tab
        IQCharacteristicsLabel  matlab.ui.control.Label
        hist_I                  matlab.ui.control.UIAxes
        hist_Q                  matlab.ui.control.UIAxes
        time_I                  matlab.ui.control.UIAxes
        time_Q                  matlab.ui.control.UIAxes
        iq_frq                  matlab.ui.control.UIAxes
        ACQUISITIONTab          matlab.ui.container.Tab
        TRACKINGTab             matlab.ui.container.Tab
        SATELLITEPOSTab         matlab.ui.container.Tab
        USERPOSTab              matlab.ui.container.Tab
    end

    properties (Access = public)
        nav; % NavTek class
        iq_file;
    end
        
    
    methods (Access = private)
       
        function probeData(app)
           
            %% Generate and plot IQ Characteristics
            fid = fopen(app.nav.fnameIQ, 'rb');

            if (fid > 0)
                %move to the start of the where we want to start post
                %processing
                fseek(fid, app.nav.skipNumBytes, 'bof');

                %find the number of samples per chipping sequence
                sampsPerCode = round(app.nav.fsamp / (app.nav.fchip / app.nav.codeLength));

                %read in the data 
                [data, count] = fread(fid, [1, 2 * 100 * sampsPerCode], app.nav.dataType);

                fclose(fid);

                if (count < 2 * 100 * sampsPerCode)
                    error("Error not enough data to load in");
                end

                %initialize plots
                timeScale = 0: 1/app.nav.fsamp : 5e-3; 
                
                %% Time domain plot
                data=data(1:2:end) + 1i .* data(2:2:end);                
                plot(app.time_I, 1000 * timeScale(1:round(sampsPerCode/2)), real(data(1:round(sampsPerCode/2)))); % Time Plot for I
                plot(app.time_Q, 1000 * timeScale(1:round(sampsPerCode/2)), imag(data(1:round(sampsPerCode/2)))); % Time Plot for Q

                %% Frequency domain plot
                [sigspec,freqv]=pwelch(data, 32768, 2048, 32768, app.nav.fsamp,'twosided');
                plot(app.iq_frq,([-(freqv(length(freqv)/2:-1:1));freqv(1:length(freqv)/2)])/1e6, 10*log10([sigspec(length(freqv)/2+1:end);
                sigspec(1:length(freqv)/2)]));

                %% Histogram
                histogram(app.hist_I,real(data), -128:128)
%                 dmax = max(abs(data)) + 1;
%                 axis tight;     adata = axis;
%                 axis(app.hist_I,[-dmax dmax adata(3) adata(4)]);
                
                histogram(app.hist_Q,imag(data), -128:128)
%                 dmax = max(abs(data)) + 1;
%                 axis tight;     adata = axis;
%                 axis(app.hist_Q,[-dmax dmax adata(3) adata(4)]);
               
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Equivalent to main function 
            
            set(app.run_IQ_button,'visible', 'off') % Allows play button to only appear after the uploaded file
            
        end

        % Button pushed function: UPLOADIQFILEButton
        function UPLOADIQFILEButtonPushed(app, event)
             app.nav = NavTek;
            
             % Wait for iq_file to be uploaded
             if app.nav.fnameIQ
%                  app.nav.userfnameIQ(app.iq_file); % Update fnameIQ in NavTek class
%                  app.nav.fnameIQ = app.iq_file;
                 set(app.run_IQ_button,'visible', 'on') % Allow play button to appear
                 app.run_IQ_button.BackgroundColor = [0.00,1.00,0.00]; % For succesfull upload, the button turns green. 
             end   
        end

        % Image clicked function: run_IQ_button
        function run_IQ_buttonClicked(app, event)
            
%             disp(app.nav.fnameIQ)
            app.probeData(); % Plot IQ Characteristics

%             app.nav.probeData();
            app.TabGroup.SelectedTab = app.IQTab; % Opens the new tab for the IQ Characteristics
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HOMETab = uitab(app.TabGroup);
            app.HOMETab.Title = 'HOME';
            app.WELCOMETOLOGPOSELabel = uilabel(app.HOMETab);
            app.WELCOMETOLOGPOSELabel.Text = 'WELCOME TO LOG POSE';
            app.StartbyuploadingaIQfileontherightLabel = uilabel(app.HOMETab);
            app.StartbyuploadingaIQfileontherightLabel.Text = 'Start by uploading a IQ file on the right.';
            app.ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel = uilabel(app.HOMETab);
            app.ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel.Text = 'A TOOL TO POST-PROCESS YOUR OWN POSITION.';
            app.UPLOADIQFILEButton = uibutton(app.HOMETab, 'push');
            app.UPLOADIQFILEButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADIQFILEButtonPushed, true);
            app.UPLOADIQFILEButton.Text = 'UPLOAD IQ FILE ';
            app.Image = uiimage(app.HOMETab);
            app.Image.ImageSource = 'car.png';
            app.run_IQ_button = uiimage(app.HOMETab);
            app.run_IQ_button.ImageClickedFcn = createCallbackFcn(app, @run_IQ_buttonClicked, true);
            app.run_IQ_button.ImageSource = 'play-button.png';
            app.IQTab = uitab(app.TabGroup);
            app.IQTab.Title = 'IQ';
            app.iq_frq = uiaxes(app.IQTab);
            title(app.iq_frq, 'Frequency domain plot')
            xlabel(app.iq_frq, 'X')
            ylabel(app.iq_frq, 'Y')
            zlabel(app.iq_frq, 'Z')
            app.time_Q = uiaxes(app.IQTab);
            title(app.time_Q, 'Time domain plot (Q)')
            xlabel(app.time_Q, 'X')
            ylabel(app.time_Q, 'Y')
            zlabel(app.time_Q, 'Z')
            app.time_I = uiaxes(app.IQTab);
            title(app.time_I, 'Time domain plot (I)')
            xlabel(app.time_I, 'X')
            ylabel(app.time_I, 'Y')
            zlabel(app.time_I, 'Z')
            app.hist_Q = uiaxes(app.IQTab);
            title(app.hist_Q, 'Histogram (Q)')
            xlabel(app.hist_Q, 'X')
            ylabel(app.hist_Q, 'Y')
            zlabel(app.hist_Q, 'Z')
            app.hist_I = uiaxes(app.IQTab);
            title(app.hist_I, 'Histogram (I)')
            xlabel(app.hist_I, 'X')
            ylabel(app.hist_I, 'Y')
            zlabel(app.hist_I, 'Z')
            app.IQCharacteristicsLabel = uilabel(app.IQTab);
            app.IQCharacteristicsLabel.Text = 'IQ Characteristics';
            app.ACQUISITIONTab = uitab(app.TabGroup);
            app.ACQUISITIONTab.Title = 'ACQUISITION';
            app.TRACKINGTab = uitab(app.TabGroup);
            app.TRACKINGTab.Title = 'TRACKING';
            app.SATELLITEPOSTab = uitab(app.TabGroup);
            app.SATELLITEPOSTab.Title = 'SATELLITE POS';
            app.USERPOSTab = uitab(app.TabGroup);
            app.USERPOSTab.Title = 'USER POS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logpose_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end